classdef m22011024 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Bolum1Tab               matlab.ui.container.Tab
        RightPanel              matlab.ui.container.Panel
        TotalGraph_part1        matlab.ui.control.UIAxes
        SingleGraph_part1       matlab.ui.control.UIAxes
        LeftPanel               matlab.ui.container.Panel
        GenlikField             matlab.ui.control.NumericEditField
        FrekansField            matlab.ui.control.NumericEditField
        FazField                matlab.ui.control.NumericEditField
        FonksiyonDropDown       matlab.ui.control.DropDown
        FonksiyonDropDownLabel  matlab.ui.control.Label
        SifirlaButton_part1     matlab.ui.control.Button
        EkleButton              matlab.ui.control.Button
        FazSlider               matlab.ui.control.Slider
        FazSliderLabel          matlab.ui.control.Label
        FrekansSlider           matlab.ui.control.Slider
        FrekansSliderLabel      matlab.ui.control.Label
        GenlikSlider            matlab.ui.control.Slider
        GenlikSliderLabel       matlab.ui.control.Label
        Bolum2Tab               matlab.ui.container.Tab
        RightPanel_2            matlab.ui.container.Panel
        SingleGraph_part2       matlab.ui.control.UIAxes
        TotalGraph2_part2       matlab.ui.control.UIAxes
        LeftPanel_2             matlab.ui.container.Panel
        TSlider_part2           matlab.ui.control.Slider
        TSliderLabel            matlab.ui.control.Label
        TField_part2            matlab.ui.control.NumericEditField
        A0Label_8               matlab.ui.control.Label
        B3Field_part2           matlab.ui.control.NumericEditField
        A0Label_7               matlab.ui.control.Label
        B2Field_part2           matlab.ui.control.NumericEditField
        A0Label_6               matlab.ui.control.Label
        B1Field_part2           matlab.ui.control.NumericEditField
        A0Label_5               matlab.ui.control.Label
        A3Field_part2           matlab.ui.control.NumericEditField
        A0Label_4               matlab.ui.control.Label
        A2Field_part2           matlab.ui.control.NumericEditField
        A0Label_3               matlab.ui.control.Label
        A1Field_part2           matlab.ui.control.NumericEditField
        A0Label_2               matlab.ui.control.Label
        A0Label                 matlab.ui.control.Label
        A0Field_part2           matlab.ui.control.NumericEditField
        SifirlaButton_part2     matlab.ui.control.Button
        CizdirButton_part2      matlab.ui.control.Button
        Bolum3Tab               matlab.ui.container.Tab
        RightPanel_3            matlab.ui.container.Panel
        SingleGraph_part3       matlab.ui.control.UIAxes
        TotalGraph_part3        matlab.ui.control.UIAxes
        LeftPanel_3             matlab.ui.container.Panel
        A0Label_17              matlab.ui.control.Label
        AField_part3            matlab.ui.control.NumericEditField
        TSlider_part3           matlab.ui.control.Slider
        TSlider_2Label          matlab.ui.control.Label
        TField_part3            matlab.ui.control.NumericEditField
        A0Label_16              matlab.ui.control.Label
        B3Field_part3           matlab.ui.control.NumericEditField
        A0Label_15              matlab.ui.control.Label
        B2Field_part3           matlab.ui.control.NumericEditField
        A0Label_14              matlab.ui.control.Label
        B1Field_part3           matlab.ui.control.NumericEditField
        A0Label_13              matlab.ui.control.Label
        A3Field_part3           matlab.ui.control.NumericEditField
        A0Label_12              matlab.ui.control.Label
        A2Field_part3           matlab.ui.control.NumericEditField
        A0Label_11              matlab.ui.control.Label
        A1Field_part3           matlab.ui.control.NumericEditField
        A0Label_10              matlab.ui.control.Label
        A0Label_9               matlab.ui.control.Label
        A0Field_part3           matlab.ui.control.NumericEditField
        SifirlaButton_part3     matlab.ui.control.Button
        CizdirButton_part3      matlab.ui.control.Button
    end


    % Bolum 1
    properties (Access = private)
        Genlik
        Frekans
        Faz
        Fonksiyon
        OrneklemeFrekansi
        Zaman
        TotalSignal
    end

    % Bolum 2
    properties (Access = private)
        OrneklemeFrekansi2
        Zaman2
        TotalSignal2
        VariablesA
        VariablesB
        A0
        W0
        T
    end

    % Bolum 3
    properties (Access = private)
        TotalSignal3
        VariablesA2
        VariablesB2
        A0_2
        W0_2
        T2
        A
    end


     methods (Access = private)

         function part1TotalCizdir(app, signal)
             app.TotalSignal = app.TotalSignal + signal;
             plot(app.TotalGraph_part1, app.Zaman, app.TotalSignal);
         end
        
         % Kod 
         function signal = part1SingleCizdir(app)
            if strcmp(app.Fonksiyon, 'Sinus')
                signal = app.Genlik * sin(2 * pi * app.Frekans * app.Zaman + app.Faz);
            else
                signal = app.Genlik * cos(2 * pi * app.Frekans * app.Zaman + app.Faz);
            end

            plot(app.SingleGraph_part1, app.Zaman, signal);
         end

         %%%%%

        function part2Cizdir(app)
            cla(app.SingleGraph_part2);
            colors = lines(3);

            % Kod
            app.W0 = 2 * pi / app.T;
            app.TotalSignal2 = (app.A0 / 2) * ones(size(app.Zaman2));
            for k = 1:3
                signal = (app.VariablesA(k) * cos(k * app.W0 * app.Zaman2) ) ...
                + (app.VariablesB(k) * sin(k * app.W0 * app.Zaman2) );
            app.TotalSignal2 = app.TotalSignal2 + signal;

            plot(app.SingleGraph_part2, app.Zaman2, signal, 'Color', colors(k,:));
            end

            %%%%%

            plot(app.TotalGraph2_part2, app.Zaman2, app.TotalSignal2);
        end

        function part3Cizdir(app)
            cla(app.TotalGraph_part3);
            cla(app.SingleGraph_part3);
            colors = lines(3);

            % Kod
            app.W0_2 = 2 * pi / app.T2;
            app.TotalSignal3 = (app.A0_2 / 2) * ones(size(app.Zaman2));
            for k = 1:3
                signal = (app.VariablesA2(k) * cos(k * app.W0_2 * app.Zaman2) ) ...
                + (app.VariablesB2(k) * sin(k * app.W0_2 * app.Zaman2) );
            app.TotalSignal3 = app.TotalSignal3 + signal;

            plot(app.SingleGraph_part3, app.Zaman2, signal, 'Color', colors(k,:));
            end

            %%%%%

            plot(app.TotalGraph_part3, app.Zaman2, app.TotalSignal3);
        end


        function startPart1(app)             
            app.OrneklemeFrekansi = 1000;
            app.Zaman = 0:1/app.OrneklemeFrekansi:1;
            app.Genlik = 1;
            app.Faz = 0;
            app.Frekans = 1;
            app.Fonksiyon = 'Sinus';
            app.TotalSignal = zeros(size(app.Zaman));
        end

        function startPart2(app)
            app.T = 2 * pi;
            app.OrneklemeFrekansi2 = 1000;
            app.Zaman2 = -6:1/app.OrneklemeFrekansi2:6;
            app.VariablesA = ones(3);
            app.VariablesB = ones(3);
            app.A0 = 1;
            app.W0 = 1;
            app.TotalSignal2 = zeros(size(app.Zaman2));
            hold(app.SingleGraph_part2, 'on');
            app.SingleGraph_part2.XAxisLocation = 'origin';
            app.SingleGraph_part2.YAxisLocation = 'origin';
            app.TotalGraph2_part2.YAxisLocation = 'origin';
            app.TotalGraph2_part2.XAxisLocation = 'origin';
        end

        function startPart3(app)
            app.T2 = 3.14;
            app.OrneklemeFrekansi2 = 1000;
            app.Zaman2 = -6:1/app.OrneklemeFrekansi2:6;
            app.VariablesA2 = ones(3);
            app.VariablesB2 = zeros(3);
            app.A0_2 = 0;
            app.A = 1;
            app.VariablesA2(1) = 0.81;
            app.VariablesA2(2) = 0;
            app.VariablesA2(3) = 0.09;
            app.TotalSignal3 = zeros(size(app.Zaman2));
            hold(app.SingleGraph_part3, 'on');
            app.SingleGraph_part3.XAxisLocation = 'origin';
            app.SingleGraph_part3.YAxisLocation = 'origin';
            app.TotalGraph_part3.YAxisLocation = 'origin';
            app.TotalGraph_part3.XAxisLocation = 'origin';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startPart1(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         delete(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selected = app.TabGroup.SelectedTab;
            switch (selected)
                case app.Bolum1Tab
                    startPart1(app);
                case app.Bolum2Tab
                    startPart2(app);
                case app.Bolum3Tab
                    startPart3(app);
            end
        end

        % Value changed function: GenlikField
        function GenlikFieldValueChanged(app, event)
            app.Genlik = app.GenlikField.Value;
            app.GenlikSlider.Value = app.GenlikField.Value;
            part1SingleCizdir(app);
        end

        % Value changed function: FrekansField
        function FrekansFieldValueChanged(app, event)
            app.Frekans = app.FrekansField.Value;
            app.FrekansSlider.Value = app.FrekansField.Value;
            part1SingleCizdir(app);
        end

        % Value changed function: FazField
        function FazFieldValueChanged(app, event)
            app.Faz = app.FazField.Value;
            app.FazSlider.Value = app.FazField.Value;
            part1SingleCizdir(app);
        end

        % Value changed function: FonksiyonDropDown
        function FonksiyonDropDownValueChanged(app, event)
            app.Fonksiyon = app.FonksiyonDropDown.Value;
            part1SingleCizdir(app);
            
        end

        % Button pushed function: SifirlaButton_part1
        function SifirlaButton_part1Pushed(app, event)
            app.Genlik = 1;
            app.Faz = 0;
            app.Frekans = 1;
            app.FrekansField.Value = 1;
            app.GenlikField.Value = 1;
            app.FazField.Value = 0;
            app.FrekansSlider.Value = 1;
            app.FazSlider.Value = 0;
            app.GenlikSlider.Value = 1;
            app.FonksiyonDropDown.Value = 'Sinus';
            app.Fonksiyon = 'Sinus';
            cla(app.SingleGraph_part1);
            cla(app.TotalGraph_part1);
            app.TotalSignal = 0;
        end

        % Button pushed function: EkleButton
        function EkleButtonPushed(app, event)
            signal = part1SingleCizdir(app);
            part1TotalCizdir(app, signal);
        end

        % Value changed function: FazSlider
        function FazSliderValueChanged(app, event)
            app.Faz = app.FazSlider.Value;
            app.FazField.Value = app.FazSlider.Value;
            part1SingleCizdir(app);
        end

        % Value changing function: FazSlider
        function FazSliderValueChanging(app, event)
            app.FazField.Value = event.Value;
            app.Faz = event.Value;
            part1SingleCizdir(app);
        end

        % Value changed function: FrekansSlider
        function FrekansSliderValueChanged(app, event)
            app.Frekans = app.FrekansSlider.Value;
            app.FrekansField.Value = app.FrekansSlider.Value;
            part1SingleCizdir(app);
        end

        % Value changing function: FrekansSlider
        function FrekansSliderValueChanging(app, event)
             app.FrekansField.Value = event.Value;
             app.Frekans = event.Value;
             part1SingleCizdir(app);
        end

        % Value changed function: GenlikSlider
        function GenlikSliderValueChanged(app, event)
            app.Genlik = app.GenlikSlider.Value;
            app.GenlikField.Value = app.GenlikSlider.Value;
            part1SingleCizdir(app);
        end

        % Value changing function: GenlikSlider
        function GenlikSliderValueChanging(app, event)
            app.GenlikField.Value = event.Value;
            app.Genlik = event.Value;
            part1SingleCizdir(app);
        end

        % Button pushed function: CizdirButton_part2
        function CizdirButton_part2Pushed(app, event)
            part2Cizdir(app);
        end

        % Button pushed function: SifirlaButton_part2
        function SifirlaButton_part2Pushed(app, event)
            app.A0 = 1;
            app.VariablesA = ones(3);
            app.VariablesB = ones(3);
            app.T = 6.283;
            app.W0 = 1;
            app.A0Field_part2.Value = 1;
            app.A1Field_part2.Value = 1;
            app.A2Field_part2.Value = 1;
            app.A3Field_part2.Value = 1;
            app.B1Field_part2.Value = 1;
            app.B2Field_part2.Value = 1;
            app.B3Field_part2.Value = 1;
            app.TField_part2.Value = 6.283;
            app.TSlider_part2.Value = 6.283;
            cla(app.TotalGraph2_part2);
            cla(app.SingleGraph_part2);
            app.TotalSignal2 = 0;
        end

        % Value changed function: A0Field_part2
        function A0Field_part2ValueChanged(app, event)
            app.A0 = app.A0Field_part2.Value;
            
        end

        % Value changed function: A1Field_part2
        function A1Field_part2ValueChanged(app, event)
            app.VariablesA(1) = app.A1Field_part2.Value;
            
        end

        % Value changed function: A2Field_part2
        function A2Field_part2ValueChanged(app, event)
            app.VariablesA(2) = app.A2Field_part2.Value;
            
        end

        % Value changed function: A3Field_part2
        function A3Field_part2ValueChanged(app, event)
            app.VariablesA(3) = app.A3Field_part2.Value;
            
        end

        % Value changed function: B1Field_part2
        function B1Field_part2ValueChanged(app, event)
            app.VariablesB(1) = app.B1Field_part2.Value;
            
        end

        % Value changed function: B2Field_part2
        function B2Field_part2ValueChanged(app, event)
            app.VariablesB(2) = app.B2Field_part2.Value;
            
        end

        % Value changed function: B3Field_part2
        function B3Field_part2ValueChanged(app, event)
            app.VariablesB(3) = app.B3Field_part2.Value;
            
        end

        % Value changed function: TField_part2
        function TField_part2ValueChanged(app, event)
            app.T = app.TField_part2.Value;
            app.TSlider_part2.Value = app.T;
            
        end

        % Value changed function: TSlider_part2
        function TSlider_part2ValueChanged(app, event)
            app.T = app.TSlider_part2.Value;
            app.TField_part2.Value = app.T;
            
        end

        % Value changing function: TSlider_part2
        function TSlider_part2ValueChanging(app, event)
            app.T = event.Value;
            app.TField_part2.Value = app.T;
            
        end

        % Value changed function: AField_part3
        function AField_part3ValueChanged(app, event)
            app.A = app.AField_part3.Value;
            app.VariablesA2(1) = 0.81 * app.A;
            app.VariablesA2(3) = 0.09 * app.A;
            app.A1Field_part3.Value = app.VariablesA2(1);
            app.A3Field_part3.Value = app.VariablesA2(3);
        end

        % Value changed function: TSlider_part3
        function TSlider_part3ValueChanged(app, event)
            app.T2 = app.TSlider_part3.Value;
            app.TField_part3.Value = app.T2;
            
        end

        % Value changing function: TSlider_part3
        function TSlider_part3ValueChanging(app, event)
            app.T2 = event.Value;
            app.TField_part3.Value = app.T2;
            
        end

        % Value changed function: TField_part3
        function TField_part3ValueChanged(app, event)
            app.T2 = app.TField_part3.Value;
            app.TSlider_part3.Value = app.T2;
            
        end

        % Value changed function: B3Field_part3
        function B3Field_part3ValueChanged(app, event)
            app.VariablesB2(3) = app.B3Field_part3.Value;
            
        end

        % Value changed function: B2Field_part3
        function B2Field_part3ValueChanged(app, event)
            app.VariablesB2(2) = app.B2Field_part3.Value;
            
        end

        % Value changed function: B1Field_part3
        function B1Field_part3ValueChanged(app, event)
            app.VariablesB2(1) = app.B1Field_part3.Value;
            
        end

        % Value changed function: A3Field_part3
        function A3Field_part3ValueChanged(app, event)
            app.VariablesA2(3) = app.A3Field_part3.Value;
            
        end

        % Value changed function: A2Field_part3
        function A2Field_part3ValueChanged(app, event)
            app.VariablesA2(2) = app.A2Field_part3.Value;
            
        end

        % Value changed function: A1Field_part3
        function A1Field_part3ValueChanged(app, event)
            app.VariablesA2(1) = app.A1Field_part3.Value;
            
        end

        % Value changed function: A0Field_part3
        function A0Field_part3ValueChanged(app, event)
            app.A0_2 = app.A0Field_part3.Value;
            
        end

        % Button pushed function: SifirlaButton_part3
        function SifirlaButton_part3Pushed(app, event)
            app.A0_2 = 0;
            app.VariablesA2(1) = 0.81;
            app.VariablesA2(2) = 0;
            app.VariablesA2(3) = 0.09;
            app.VariablesB2 = zeros(3);
            app.T2 = 3.14;
            app.A0Field_part3.Value = 0;
            app.A1Field_part3.Value = 0.81;
            app.A2Field_part3.Value = 0;
            app.A3Field_part3.Value = 0.09;
            app.B1Field_part3.Value = 0;
            app.B2Field_part3.Value = 0;
            app.B3Field_part3.Value = 0;
            app.AField_part3.Value = 1;
            app.TField_part3.Value = 3.14;
            app.TSlider_part3.Value = 3.14;
            cla(app.TotalGraph_part3);
            cla(app.SingleGraph_part3);
            app.TotalSignal3 = 0;
        end

        % Button pushed function: CizdirButton_part3
        function CizdirButton_part3Pushed(app, event)
            part3Cizdir(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Bolum1Tab = uitab(app.TabGroup);
            app.Bolum1Tab.Title = 'Bolum 1';
            app.LeftPanel = uipanel(app.Bolum1Tab);
            app.GenlikSliderLabel = uilabel(app.LeftPanel);
            app.GenlikSliderLabel.Text = 'Genlik';
            app.GenlikSlider = uislider(app.LeftPanel);
            app.GenlikSlider.Limits = [0 10];
            app.GenlikSlider.ValueChangedFcn = createCallbackFcn(app, @GenlikSliderValueChanged, true);
            app.GenlikSlider.ValueChangingFcn = createCallbackFcn(app, @GenlikSliderValueChanging, true);
            app.GenlikSlider.Value = 1;
            app.FrekansSliderLabel = uilabel(app.LeftPanel);
            app.FrekansSliderLabel.Text = 'Frekans ';
            app.FrekansSlider = uislider(app.LeftPanel);
            app.FrekansSlider.ValueChangedFcn = createCallbackFcn(app, @FrekansSliderValueChanged, true);
            app.FrekansSlider.ValueChangingFcn = createCallbackFcn(app, @FrekansSliderValueChanging, true);
            app.FrekansSlider.Value = 1;
            app.FazSliderLabel = uilabel(app.LeftPanel);
            app.FazSliderLabel.Text = 'Faz';
            app.FazSlider = uislider(app.LeftPanel);
            app.FazSlider.Limits = [-3.14 3.14];
            app.FazSlider.ValueChangedFcn = createCallbackFcn(app, @FazSliderValueChanged, true);
            app.FazSlider.ValueChangingFcn = createCallbackFcn(app, @FazSliderValueChanging, true);
            app.EkleButton = uibutton(app.LeftPanel, 'push');
            app.EkleButton.ButtonPushedFcn = createCallbackFcn(app, @EkleButtonPushed, true);
            app.EkleButton.Text = 'Ekle';
            app.SifirlaButton_part1 = uibutton(app.LeftPanel, 'push');
            app.SifirlaButton_part1.ButtonPushedFcn = createCallbackFcn(app, @SifirlaButton_part1Pushed, true);
            app.SifirlaButton_part1.Text = 'Sifirla';
            app.FonksiyonDropDownLabel = uilabel(app.LeftPanel);
            app.FonksiyonDropDownLabel.Text = 'Fonksiyon';
            app.FonksiyonDropDown = uidropdown(app.LeftPanel);
            app.FonksiyonDropDown.Items = {'Sinus', 'Cosinus'};
            app.FonksiyonDropDown.ValueChangedFcn = createCallbackFcn(app, @FonksiyonDropDownValueChanged, true);
            app.FonksiyonDropDown.Value = 'Sinus';
            app.FazField = uieditfield(app.LeftPanel, 'numeric');
            app.FazField.ValueChangedFcn = createCallbackFcn(app, @FazFieldValueChanged, true);
            app.FrekansField = uieditfield(app.LeftPanel, 'numeric');
            app.FrekansField.ValueChangedFcn = createCallbackFcn(app, @FrekansFieldValueChanged, true);
            app.FrekansField.Value = 1;
            app.GenlikField = uieditfield(app.LeftPanel, 'numeric');
            app.GenlikField.ValueChangedFcn = createCallbackFcn(app, @GenlikFieldValueChanged, true);
            app.GenlikField.Value = 1;
            app.RightPanel = uipanel(app.Bolum1Tab);
            app.SingleGraph_part1 = uiaxes(app.RightPanel);
            title(app.SingleGraph_part1, 'Sinyal')
            xlabel(app.SingleGraph_part1, 'X')
            ylabel(app.SingleGraph_part1, 'Y')
            zlabel(app.SingleGraph_part1, 'Z')
            app.TotalGraph_part1 = uiaxes(app.RightPanel);
            title(app.TotalGraph_part1, 'Sinyaller Toplami')
            xlabel(app.TotalGraph_part1, 'X')
            ylabel(app.TotalGraph_part1, 'Y')
            zlabel(app.TotalGraph_part1, 'Z')
            app.Bolum2Tab = uitab(app.TabGroup);
            app.Bolum2Tab.Title = 'Bolum 2';
            app.LeftPanel_2 = uipanel(app.Bolum2Tab);
            app.CizdirButton_part2 = uibutton(app.LeftPanel_2, 'push');
            app.CizdirButton_part2.ButtonPushedFcn = createCallbackFcn(app, @CizdirButton_part2Pushed, true);
            app.CizdirButton_part2.Text = 'Cizdir';
            app.SifirlaButton_part2 = uibutton(app.LeftPanel_2, 'push');
            app.SifirlaButton_part2.ButtonPushedFcn = createCallbackFcn(app, @SifirlaButton_part2Pushed, true);
            app.SifirlaButton_part2.Text = 'Sifirla';
            app.A0Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.A0Field_part2.ValueChangedFcn = createCallbackFcn(app, @A0Field_part2ValueChanged, true);
            app.A0Field_part2.Value = 1;
            app.A0Label = uilabel(app.LeftPanel_2);
            app.A0Label.Text = 'A0';
            app.A0Label_2 = uilabel(app.LeftPanel_2);
            app.A0Label_2.Text = 'A1';
            app.A1Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.A1Field_part2.ValueChangedFcn = createCallbackFcn(app, @A1Field_part2ValueChanged, true);
            app.A1Field_part2.Value = 1;
            app.A0Label_3 = uilabel(app.LeftPanel_2);
            app.A0Label_3.Text = 'A2';
            app.A2Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.A2Field_part2.ValueChangedFcn = createCallbackFcn(app, @A2Field_part2ValueChanged, true);
            app.A2Field_part2.Value = 1;
            app.A0Label_4 = uilabel(app.LeftPanel_2);
            app.A0Label_4.Text = 'A3';
            app.A3Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.A3Field_part2.ValueChangedFcn = createCallbackFcn(app, @A3Field_part2ValueChanged, true);
            app.A3Field_part2.Value = 1;
            app.A0Label_5 = uilabel(app.LeftPanel_2);
            app.A0Label_5.Text = 'B1';
            app.B1Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.B1Field_part2.ValueChangedFcn = createCallbackFcn(app, @B1Field_part2ValueChanged, true);
            app.B1Field_part2.Value = 1;
            app.A0Label_6 = uilabel(app.LeftPanel_2);
            app.A0Label_6.Text = 'B2';
            app.B2Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.B2Field_part2.ValueChangedFcn = createCallbackFcn(app, @B2Field_part2ValueChanged, true);
            app.B2Field_part2.Value = 1;
            app.A0Label_7 = uilabel(app.LeftPanel_2);
            app.A0Label_7.Text = 'B3';
            app.B3Field_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.B3Field_part2.ValueChangedFcn = createCallbackFcn(app, @B3Field_part2ValueChanged, true);
            app.B3Field_part2.Value = 1;
            app.A0Label_8 = uilabel(app.LeftPanel_2);
            app.A0Label_8.Text = 'T';
            app.TField_part2 = uieditfield(app.LeftPanel_2, 'numeric');
            app.TField_part2.ValueChangedFcn = createCallbackFcn(app, @TField_part2ValueChanged, true);
            app.TField_part2.Value = 6.283;
            app.TSliderLabel = uilabel(app.LeftPanel_2);
            app.TSliderLabel.Text = 'T';
            app.TSlider_part2 = uislider(app.LeftPanel_2);
            app.TSlider_part2.Limits = [0 6.283];
            app.TSlider_part2.ValueChangedFcn = createCallbackFcn(app, @TSlider_part2ValueChanged, true);
            app.TSlider_part2.ValueChangingFcn = createCallbackFcn(app, @TSlider_part2ValueChanging, true);
            app.TSlider_part2.Value = 6.283;
            app.RightPanel_2 = uipanel(app.Bolum2Tab);
            app.TotalGraph2_part2 = uiaxes(app.RightPanel_2);
            title(app.TotalGraph2_part2, 'Sinyaller Toplami')
            xlabel(app.TotalGraph2_part2, 'X')
            ylabel(app.TotalGraph2_part2, 'Y')
            zlabel(app.TotalGraph2_part2, 'Z')
            app.SingleGraph_part2 = uiaxes(app.RightPanel_2);
            title(app.SingleGraph_part2, 'Tekil')
            xlabel(app.SingleGraph_part2, 'X')
            ylabel(app.SingleGraph_part2, 'Y')
            zlabel(app.SingleGraph_part2, 'Z')
            app.Bolum3Tab = uitab(app.TabGroup);
            app.Bolum3Tab.Title = 'Bolum 3';
            app.LeftPanel_3 = uipanel(app.Bolum3Tab);
            app.CizdirButton_part3 = uibutton(app.LeftPanel_3, 'push');
            app.CizdirButton_part3.ButtonPushedFcn = createCallbackFcn(app, @CizdirButton_part3Pushed, true);
            app.CizdirButton_part3.Text = 'Cizdir';
            app.SifirlaButton_part3 = uibutton(app.LeftPanel_3, 'push');
            app.SifirlaButton_part3.ButtonPushedFcn = createCallbackFcn(app, @SifirlaButton_part3Pushed, true);
            app.SifirlaButton_part3.Text = 'Sifirla';
            app.A0Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.A0Field_part3.ValueChangedFcn = createCallbackFcn(app, @A0Field_part3ValueChanged, true);
            app.A0Label_9 = uilabel(app.LeftPanel_3);
            app.A0Label_9.Text = 'A0';
            app.A0Label_10 = uilabel(app.LeftPanel_3);
            app.A0Label_10.Text = 'A1';
            app.A1Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.A1Field_part3.ValueChangedFcn = createCallbackFcn(app, @A1Field_part3ValueChanged, true);
            app.A1Field_part3.Value = 0.81;
            app.A0Label_11 = uilabel(app.LeftPanel_3);
            app.A0Label_11.Text = 'A2';
            app.A2Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.A2Field_part3.ValueChangedFcn = createCallbackFcn(app, @A2Field_part3ValueChanged, true);
            app.A0Label_12 = uilabel(app.LeftPanel_3);
            app.A0Label_12.Text = 'A3';
            app.A3Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.A3Field_part3.ValueChangedFcn = createCallbackFcn(app, @A3Field_part3ValueChanged, true);
            app.A3Field_part3.Value = 0.09;
            app.A0Label_13 = uilabel(app.LeftPanel_3);
            app.A0Label_13.Text = 'B1';
            app.B1Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.B1Field_part3.ValueChangedFcn = createCallbackFcn(app, @B1Field_part3ValueChanged, true);
            app.A0Label_14 = uilabel(app.LeftPanel_3);
            app.A0Label_14.Text = 'B2';
            app.B2Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.B2Field_part3.ValueChangedFcn = createCallbackFcn(app, @B2Field_part3ValueChanged, true);
            app.A0Label_15 = uilabel(app.LeftPanel_3);
            app.A0Label_15.Text = 'B3';
            app.B3Field_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.B3Field_part3.ValueChangedFcn = createCallbackFcn(app, @B3Field_part3ValueChanged, true);
            app.A0Label_16 = uilabel(app.LeftPanel_3);
            app.A0Label_16.Text = 'T';
            app.TField_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.TField_part3.ValueChangedFcn = createCallbackFcn(app, @TField_part3ValueChanged, true);
            app.TField_part3.Value = 3.14;
            app.TSlider_2Label = uilabel(app.LeftPanel_3);
            app.TSlider_2Label.Text = 'T';
            app.TSlider_part3 = uislider(app.LeftPanel_3);
            app.TSlider_part3.Limits = [0 6.283];
            app.TSlider_part3.ValueChangedFcn = createCallbackFcn(app, @TSlider_part3ValueChanged, true);
            app.TSlider_part3.ValueChangingFcn = createCallbackFcn(app, @TSlider_part3ValueChanging, true);
            app.TSlider_part3.Value = 3.14;
            app.AField_part3 = uieditfield(app.LeftPanel_3, 'numeric');
            app.AField_part3.ValueChangedFcn = createCallbackFcn(app, @AField_part3ValueChanged, true);
            app.AField_part3.Value = 1;
            app.A0Label_17 = uilabel(app.LeftPanel_3);
            app.A0Label_17.Text = 'A';
            app.RightPanel_3 = uipanel(app.Bolum3Tab);
            app.TotalGraph_part3 = uiaxes(app.RightPanel_3);
            title(app.TotalGraph_part3, 'Sinyaller Toplami')
            xlabel(app.TotalGraph_part3, 'X')
            ylabel(app.TotalGraph_part3, 'Y')
            zlabel(app.TotalGraph_part3, 'Z')
            app.SingleGraph_part3 = uiaxes(app.RightPanel_3);
            title(app.SingleGraph_part3, 'Tekil')
            xlabel(app.SingleGraph_part3, 'X')
            ylabel(app.SingleGraph_part3, 'Y')
            zlabel(app.SingleGraph_part3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = m22011024

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end