classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SineCosineGrntleButton  matlab.ui.control.Button
        wEditField              matlab.ui.control.NumericEditField
        wEditFieldLabel         matlab.ui.control.Label
        TEditField              matlab.ui.control.NumericEditField
        TEditFieldLabel         matlab.ui.control.Label
        a0EditField             matlab.ui.control.NumericEditField
        a0EditFieldLabel        matlab.ui.control.Label
        b3EditField             matlab.ui.control.NumericEditField
        b3EditFieldLabel        matlab.ui.control.Label
        b2EditField             matlab.ui.control.NumericEditField
        b2EditFieldLabel        matlab.ui.control.Label
        b1EditField             matlab.ui.control.NumericEditField
        b1EditFieldLabel        matlab.ui.control.Label
        a3EditField             matlab.ui.control.NumericEditField
        a3EditFieldLabel        matlab.ui.control.Label
        a2EditField             matlab.ui.control.NumericEditField
        a2EditFieldLabel        matlab.ui.control.Label
        a1EditField             matlab.ui.control.NumericEditField
        a1EditFieldLabel        matlab.ui.control.Label
        GrntleButton            matlab.ui.control.Button
        Faz3EditField           matlab.ui.control.NumericEditField
        Faz3EditFieldLabel      matlab.ui.control.Label
        Frekans3EditField       matlab.ui.control.NumericEditField
        Frekans3EditFieldLabel  matlab.ui.control.Label
        Genlik3EditField        matlab.ui.control.NumericEditField
        Genlik3EditFieldLabel   matlab.ui.control.Label
        Faz2EditField           matlab.ui.control.NumericEditField
        Faz2EditFieldLabel      matlab.ui.control.Label
        Frekans2EditField       matlab.ui.control.NumericEditField
        Frekans2EditFieldLabel  matlab.ui.control.Label
        Genlik2EditField        matlab.ui.control.NumericEditField
        Genlik2EditFieldLabel   matlab.ui.control.Label
        Faz1EditField           matlab.ui.control.NumericEditField
        Faz1EditFieldLabel      matlab.ui.control.Label
        Frekans1EditField       matlab.ui.control.NumericEditField
        Frekans1EditFieldLabel  matlab.ui.control.Label
        Genlik1EditField        matlab.ui.control.NumericEditField
        Genlik1EditFieldLabel   matlab.ui.control.Label
        UIAxes_12               matlab.ui.control.UIAxes
        UIAxes_11               matlab.ui.control.UIAxes
        UIAxes_10               matlab.ui.control.UIAxes
        UIAxes_9                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrntleButton
        function GrntleButtonPushed(app, event)
        t = 0:0.001:1;
    
        A1 = app.Genlik1EditField.Value;
        f1 = app.Frekans1EditField.Value;
        th1 = app.Faz1EditField.Value;
    
        A2 = app.Genlik2EditField.Value;
        f2 = app.Frekans2EditField.Value;
        th2 = app.Faz2EditField.Value;
    
        A3 = app.Genlik3EditField.Value;
        f3 = app.Frekans3EditField.Value;
        th3 = app.Faz3EditField.Value;
    
        sin1 = A1 * sin(2*pi*f1*t + deg2rad(th1));
        cos1 = A1 * cos(2*pi*f1*t + deg2rad(th1));
        sin2 = A2 * sin(2*pi*f2*t + deg2rad(th2));
        cos2 = A2 * cos(2*pi*f2*t + deg2rad(th2));
        sin3 = A3 * sin(2*pi*f3*t + deg2rad(th3));
        cos3 = A3 * cos(2*pi*f3*t + deg2rad(th3));
    
        toplam1 = sin1 + cos1;
        toplam2 = sin2 + cos2;
        toplam3 = sin3 + cos3;
    
        totalSin = sin1 + sin2 + sin3;
        totalCos = cos1 + cos2 + cos3;
        totalSignal = totalSin + totalCos;
    
        %grafikler
        plot(app.UIAxes, t, sin1); title(app.UIAxes, 'Sin 1');
        plot(app.UIAxes_2, t, sin2); title(app.UIAxes_2, 'Sin 2');
        plot(app.UIAxes_3, t, sin3); title(app.UIAxes_3, 'Sin 3');
    
        plot(app.UIAxes_4, t, cos1); title(app.UIAxes_4, 'Cos 1');
        plot(app.UIAxes_5, t, cos2); title(app.UIAxes_5, 'Cos 2');
        plot(app.UIAxes_6, t, cos3); title(app.UIAxes_6, 'Cos 3');
    
        plot(app.UIAxes_7, t, toplam1); title(app.UIAxes_7, 'Total 1');
        plot(app.UIAxes_8, t, toplam2); title(app.UIAxes_8, 'Total 2');
        plot(app.UIAxes_9, t, toplam3); title(app.UIAxes_9, 'Total 3');
    
        plot(app.UIAxes_10, t, totalSin); title(app.UIAxes_10, 'Total Sin');
        plot(app.UIAxes_11, t, totalCos); title(app.UIAxes_11, 'Total Cos');
        plot(app.UIAxes_12, t, totalSignal); title(app.UIAxes_12, 'Total Signal');
        end

        % Button pushed function: SineCosineGrntleButton
        function SineCosineGrntleButtonPushed(app, event)
        a0 = app.a0EditField.Value;
        a1 = app.a1EditField.Value;
        a2 = app.a2EditField.Value;
        a3 = app.a3EditField.Value;
        
        b1 = app.b1EditField.Value;
        b2 = app.b2EditField.Value;
        b3 = app.b3EditField.Value;
        
        w = app.wEditField.Value;
        T = app.TEditField.Value;
        t = 0:0.001:T;
        
        cos1 = a1 * cos(w * t);
        cos2 = a2 * cos(2 * w * t);
        cos3 = a3 * cos(3 * w * t);
        
        sin1 = b1 * sin(w * t);
        sin2 = b2 * sin(2 * w * t);
        sin3 = b3 * sin(3 * w * t);
        
        term1 = cos1 + sin1;
        term2 = cos2 + sin2;
        term3 = cos3 + sin3;
        
        fourier_sum = a0/2 + term1 + term2 + term3;
        
        %grafikler
        plot(app.UIAxes, t, cos1); 
        title(app.UIAxes, 'a1cos(wt)');
        ylabel(app.UIAxes, 'Amplitude');
        
        plot(app.UIAxes_2, t, cos2);
        title(app.UIAxes_2, 'a2cos(2wt)');
        ylabel(app.UIAxes_2, 'Amplitude');
        
        plot(app.UIAxes_3, t, cos3);
        title(app.UIAxes_3, 'a3cos(3wt)');
        ylabel(app.UIAxes_3, 'Amplitude');
        
        plot(app.UIAxes_4, t, sin1);
        title(app.UIAxes_4, 'b1sin(wt)');
        ylabel(app.UIAxes_4, 'Amplitude');
        
        plot(app.UIAxes_5, t, sin2);
        title(app.UIAxes_5, 'b2sin(2wt)');
        ylabel(app.UIAxes_5, 'Amplitude');
        
        plot(app.UIAxes_6, t, sin3);
        title(app.UIAxes_6, 'b3sin(3wt)');
        ylabel(app.UIAxes_6, 'Amplitude');
        
        plot(app.UIAxes_7, t, term1);
        title(app.UIAxes_7, 'Total 1');
        ylabel(app.UIAxes_7, 'Amplitude');
        
        plot(app.UIAxes_8, t, term2);
        title(app.UIAxes_8, 'Total 2');
        ylabel(app.UIAxes_8, 'Amplitude');
        
        plot(app.UIAxes_9, t, term3);
        title(app.UIAxes_9, 'Total 3');
        ylabel(app.UIAxes_9, 'Amplitude');
        
        total_cos = cos1 + cos2 + cos3;
        plot(app.UIAxes_10, t, total_cos);
        title(app.UIAxes_10, 'Total Cos');
        ylabel(app.UIAxes_10, 'Amplitude');
        
        total_sin = sin1 + sin2 + sin3;
        plot(app.UIAxes_11, t, total_sin);
        title(app.UIAxes_11, 'Total Sin');
        ylabel(app.UIAxes_11, 'Amplitude');
        
        plot(app.UIAxes_12, t, fourier_sum);
        title(app.UIAxes_12, 'Total Fourier');
        ylabel(app.UIAxes_12, 'Amplitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sin 1')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'x(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Sin 2')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'x(t)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Sin 3')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'x(t)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Cos 1')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'x(t)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Cos 2')
            xlabel(app.UIAxes_5, 'Time')
            ylabel(app.UIAxes_5, 'x(t)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Cos 3')
            xlabel(app.UIAxes_6, 'Time')
            ylabel(app.UIAxes_6, 'x(t)')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Total 1')
            xlabel(app.UIAxes_7, 'Time')
            ylabel(app.UIAxes_7, 'x(t)')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Total 2')
            xlabel(app.UIAxes_8, 'Time')
            ylabel(app.UIAxes_8, 'x(t)')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Total 3')
            xlabel(app.UIAxes_9, 'Time')
            ylabel(app.UIAxes_9, 'x(t)')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Total Sin')
            xlabel(app.UIAxes_10, 'Time')
            ylabel(app.UIAxes_10, 'x(t)')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.UIFigure);
            title(app.UIAxes_11, 'Total Cos')
            xlabel(app.UIAxes_11, 'Time')
            ylabel(app.UIAxes_11, 'x(t)')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.UIFigure);
            title(app.UIAxes_12, 'Total Signal')
            xlabel(app.UIAxes_12, 'Time')
            ylabel(app.UIAxes_12, 'x(t)')
            zlabel(app.UIAxes_12, 'Z')
            app.Genlik1EditFieldLabel = uilabel(app.UIFigure);
            app.Genlik1EditFieldLabel.Text = 'Genlik1';
            app.Genlik1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frekans1EditFieldLabel = uilabel(app.UIFigure);
            app.Frekans1EditFieldLabel.Text = 'Frekans1';
            app.Frekans1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Faz1EditFieldLabel = uilabel(app.UIFigure);
            app.Faz1EditFieldLabel.Text = 'Faz1';
            app.Faz1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Genlik2EditFieldLabel = uilabel(app.UIFigure);
            app.Genlik2EditFieldLabel.Text = 'Genlik2';
            app.Genlik2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frekans2EditFieldLabel = uilabel(app.UIFigure);
            app.Frekans2EditFieldLabel.Text = 'Frekans2';
            app.Frekans2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Faz2EditFieldLabel = uilabel(app.UIFigure);
            app.Faz2EditFieldLabel.Text = 'Faz2';
            app.Faz2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Genlik3EditFieldLabel = uilabel(app.UIFigure);
            app.Genlik3EditFieldLabel.Text = 'Genlik3';
            app.Genlik3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Frekans3EditFieldLabel = uilabel(app.UIFigure);
            app.Frekans3EditFieldLabel.Text = 'Frekans3';
            app.Frekans3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Faz3EditFieldLabel = uilabel(app.UIFigure);
            app.Faz3EditFieldLabel.Text = 'Faz3';
            app.Faz3EditField = uieditfield(app.UIFigure, 'numeric');
            app.GrntleButton = uibutton(app.UIFigure, 'push');
            app.GrntleButton.ButtonPushedFcn = createCallbackFcn(app, @GrntleButtonPushed, true);
            app.GrntleButton.Text = 'Görüntüle';
            app.a1EditFieldLabel = uilabel(app.UIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.UIFigure, 'numeric');
            app.a2EditFieldLabel = uilabel(app.UIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.UIFigure, 'numeric');
            app.a3EditFieldLabel = uilabel(app.UIFigure);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.UIFigure, 'numeric');
            app.b1EditFieldLabel = uilabel(app.UIFigure);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1EditField = uieditfield(app.UIFigure, 'numeric');
            app.b2EditFieldLabel = uilabel(app.UIFigure);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2EditField = uieditfield(app.UIFigure, 'numeric');
            app.b3EditFieldLabel = uilabel(app.UIFigure);
            app.b3EditFieldLabel.Text = 'b3';
            app.b3EditField = uieditfield(app.UIFigure, 'numeric');
            app.a0EditFieldLabel = uilabel(app.UIFigure);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.wEditFieldLabel = uilabel(app.UIFigure);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.UIFigure, 'numeric');
            app.SineCosineGrntleButton = uibutton(app.UIFigure, 'push');
            app.SineCosineGrntleButton.ButtonPushedFcn = createCallbackFcn(app, @SineCosineGrntleButtonPushed, true);
            app.SineCosineGrntleButton.Text = {'Sine-Cosine'; 'Görüntüle'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end